classdef crtc_analyze_across_groups_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTCToolkitAnalyzeAcrossGroupsAppUIFigure  matlab.ui.Figure
        StartanalysisButton           matlab.ui.control.Button
        DataFolderEditField           matlab.ui.control.EditField
        DataFolderEditFieldLabel      matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        Label                         matlab.ui.control.Label
        PlottingPanel                 matlab.ui.container.Panel
        YaxisboundsLabel              matlab.ui.control.Label
        UpperEditField                matlab.ui.control.NumericEditField
        UpperEditFieldLabel           matlab.ui.control.Label
        LowerEditField                matlab.ui.control.NumericEditField
        LowerEditFieldLabel           matlab.ui.control.Label
        Group8DropDown                matlab.ui.control.DropDown
        Group8DropDownLabel           matlab.ui.control.Label
        Group7DropDown                matlab.ui.control.DropDown
        Group7DropDownLabel           matlab.ui.control.Label
        Group6DropDown                matlab.ui.control.DropDown
        Group6DropDownLabel           matlab.ui.control.Label
        Group5DropDown                matlab.ui.control.DropDown
        Group5DropDownLabel           matlab.ui.control.Label
        Group4DropDown                matlab.ui.control.DropDown
        Group4DropDownLabel           matlab.ui.control.Label
        Group3DropDown                matlab.ui.control.DropDown
        Group3DropDownLabel           matlab.ui.control.Label
        Group2DropDown                matlab.ui.control.DropDown
        Group2DropDownLabel           matlab.ui.control.Label
        Group1DropDown                matlab.ui.control.DropDown
        Group1DropDownLabel           matlab.ui.control.Label
        ExperimentnameEditField       matlab.ui.control.EditField
        ExperimentnameEditFieldLabel  matlab.ui.control.Label
        SavedataCheckBox              matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            datadir = uigetdir(pwd, 'Select the folder containing subfolders by group');
            drawnow;
            figure(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure);
            if datadir == 0
                return
            else
                app.DataFolderEditField.Value = datadir;
            end
        end

        % Button pushed function: StartanalysisButton
        function StartanalysisButtonPushed(app, event)
            expname = app.ExperimentnameEditField.Value;
            dosave = app.SavedataCheckBox.Value;
            ylimrange = [app.LowerEditField.Value, app.UpperEditField.Value];
            colidx_all = [str2double(app.Group1DropDown.Value),str2double(app.Group2DropDown.Value),str2double(app.Group3DropDown.Value),str2double(app.Group4DropDown.Value),str2double(app.Group5DropDown.Value),str2double(app.Group6DropDown.Value),str2double(app.Group7DropDown.Value),str2double(app.Group8DropDown.Value)];
            colidx = colidx_all(find(colidx_all, 1, "first"):find(colidx_all, 1, "last"));
            
            if ~isfolder(app.DataFolderEditField.Value)
                uiconfirm(app.CRTCToolkitPerConditionAnalysisAppUIFigure, "Please provide a valid data directory first!", "No data directory provided");
                return;
            end
            currdir = pwd;
            cd(app.DataFolderEditField.Value);
            app.StartanalysisButton.Text = "Analyzing...";
            drawnow;
            crtc_analyze_across_groups_f(expname,colidx,ylimrange,dosave);
            cd(currdir);
            app.StartanalysisButton.Text = "Complete!";
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure = uifigure('Visible', 'off');
            app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure.Name = 'CRTC Toolkit - Analyze Across Groups App';
            app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure.Icon = 'icon.png';
            app.SavedataCheckBox = uicheckbox(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure);
            app.SavedataCheckBox.Text = 'Save data';
            app.SavedataCheckBox.Value = true;
            app.ExperimentnameEditFieldLabel = uilabel(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure);
            app.ExperimentnameEditFieldLabel.Text = 'Experiment name';
            app.ExperimentnameEditField = uieditfield(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure, 'text');
            app.PlottingPanel = uipanel(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure);
            app.PlottingPanel.Title = 'Plotting';
            app.Group1DropDownLabel = uilabel(app.PlottingPanel);
            app.Group1DropDownLabel.Text = 'Group 1';
            app.Group1DropDown = uidropdown(app.PlottingPanel);
            app.Group1DropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Purple', 'Brown', 'Gray', 'Black', 'N/A'};
            app.Group1DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '0'};
            app.Group1DropDown.Value = '1';
            app.Group2DropDownLabel = uilabel(app.PlottingPanel);
            app.Group2DropDownLabel.Text = 'Group 2';
            app.Group2DropDown = uidropdown(app.PlottingPanel);
            app.Group2DropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Purple', 'Brown', 'Gray', 'Black', 'N/A'};
            app.Group2DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '0'};
            app.Group2DropDown.Value = '2';
            app.Group3DropDownLabel = uilabel(app.PlottingPanel);
            app.Group3DropDownLabel.Text = 'Group 3';
            app.Group3DropDown = uidropdown(app.PlottingPanel);
            app.Group3DropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Purple', 'Brown', 'Gray', 'Black', 'N/A'};
            app.Group3DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '0'};
            app.Group3DropDown.Value = '0';
            app.Group4DropDownLabel = uilabel(app.PlottingPanel);
            app.Group4DropDownLabel.Text = 'Group 4';
            app.Group4DropDown = uidropdown(app.PlottingPanel);
            app.Group4DropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Purple', 'Brown', 'Gray', 'Black', 'N/A'};
            app.Group4DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '0'};
            app.Group4DropDown.Value = '0';
            app.Group5DropDownLabel = uilabel(app.PlottingPanel);
            app.Group5DropDownLabel.Text = 'Group 5';
            app.Group5DropDown = uidropdown(app.PlottingPanel);
            app.Group5DropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Purple', 'Brown', 'Gray', 'Black', 'N/A'};
            app.Group5DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '0'};
            app.Group5DropDown.Value = '0';
            app.Group6DropDownLabel = uilabel(app.PlottingPanel);
            app.Group6DropDownLabel.Text = 'Group 6';
            app.Group6DropDown = uidropdown(app.PlottingPanel);
            app.Group6DropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Purple', 'Brown', 'Gray', 'Black', 'N/A'};
            app.Group6DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '0'};
            app.Group6DropDown.Value = '0';
            app.Group7DropDownLabel = uilabel(app.PlottingPanel);
            app.Group7DropDownLabel.Text = 'Group 7';
            app.Group7DropDown = uidropdown(app.PlottingPanel);
            app.Group7DropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Purple', 'Brown', 'Gray', 'Black', 'N/A'};
            app.Group7DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '0'};
            app.Group7DropDown.Value = '0';
            app.Group8DropDownLabel = uilabel(app.PlottingPanel);
            app.Group8DropDownLabel.Text = 'Group 8';
            app.Group8DropDown = uidropdown(app.PlottingPanel);
            app.Group8DropDown.Items = {'Red', 'Green', 'Blue', 'Orange', 'Purple', 'Brown', 'Gray', 'Black', 'N/A'};
            app.Group8DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '0'};
            app.Group8DropDown.Value = '0';
            app.LowerEditFieldLabel = uilabel(app.PlottingPanel);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.PlottingPanel, 'numeric');
            app.LowerEditField.Value = -0.1;
            app.UpperEditFieldLabel = uilabel(app.PlottingPanel);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.PlottingPanel, 'numeric');
            app.UpperEditField.Value = 0.5;
            app.YaxisboundsLabel = uilabel(app.PlottingPanel);
            app.YaxisboundsLabel.Text = 'Y-axis bounds';
            app.Label = uilabel(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure);
            app.Label.Text = {'Select a folder that contains subfolders for each group, each of which contains subfolders for each fly (e.g,,'; ' ''FLY01'', ''FLY02'', etc.), with each fly folder containing data4*.mat files.'};
            app.BrowseButton = uibutton(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.DataFolderEditFieldLabel = uilabel(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure);
            app.DataFolderEditFieldLabel.Text = 'Data Folder';
            app.DataFolderEditField = uieditfield(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure, 'text');
            app.StartanalysisButton = uibutton(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure, 'push');
            app.StartanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartanalysisButtonPushed, true);
            app.StartanalysisButton.Text = 'Start analysis';
            % Show the figure after all components are created
            app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crtc_analyze_across_groups_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTCToolkitAnalyzeAcrossGroupsAppUIFigure)
        end
    end
end