classdef crtc_correct_cells_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTCToolkitCheckCorrectCellsAppUIFigure  matlab.ui.Figure
        SelectFolderButton   matlab.ui.control.Button
        ImageEditFieldLabel  matlab.ui.control.Label
        ImageEditField       matlab.ui.control.NumericEditField
        OutOfLabel           matlab.ui.control.Label
        NumImagesLabel       matlab.ui.control.Label
        PrevImageButton      matlab.ui.control.Button
        NextImageButton      matlab.ui.control.Button
        CellEditFieldLabel   matlab.ui.control.Label
        CellEditField        matlab.ui.control.NumericEditField
        OutOfLabel2          matlab.ui.control.Label
        NumCellsLabel        matlab.ui.control.Label
        NextCellButton       matlab.ui.control.Button
        PrevCellButton       matlab.ui.control.Button
        DrawCellROIButton    matlab.ui.control.Button
        DrawNucROIButton     matlab.ui.control.Button
        SaveDataButton       matlab.ui.control.Button
        CurrImageLabel       matlab.ui.control.Label
        ShowRBButton         matlab.ui.control.Button
        DropCellButton       matlab.ui.control.Button
        DrawingAxes          matlab.ui.control.UIAxes
        FeedbackAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filesdir = pwd;%"D:\"    % folder in which currently correct cell files are stored
        filenames           % list of names of desired files in filesdir
        files               % array of file
        redchan
        bluechan
    end
    
    methods (Access = private)
        
        function loadImages(app, chans)
            app.CurrImageLabel.Text = app.files(app.ImageEditField.Value).CZIFILEPATH;
            
            rgb = app.files(app.ImageEditField.Value).CELL(app.CellEditField.Value).IMAGE;
            %rgb = uint8(rgb./16);
            rgb = double(rgb) ./ 4096;
            
            for i = 1:3
                if ~any(chans(:) == i)
                    rgb(:,:,i) = 0;
                else
                    rgb(:,:,i) = rgb(:,:,i) ./ max(max(rgb(:,:,i)));% ALL IMAGES CONTRAST MAXIMIZED
                end
            end
            % Make the image magenta(nuc)/green(cyto)
            RGB = cat(3,rgb(:,:,3),rgb(:,:,1),rgb(:,:,3));
            imshow(RGB, [], 'initialmag','fit','parent',app.DrawingAxes);
            currCell = app.files(app.ImageEditField.Value).CELL(app.CellEditField.Value).cellROI;
            if ~isempty(currCell) && any(chans(:) == 1)
                drawpolygon(app.DrawingAxes, 'Position', currCell, 'color','y','label','cell');
            end
            currNuc = app.files(app.ImageEditField.Value).CELL(app.CellEditField.Value).nucROI;
            if ~isempty(currNuc) && any(chans(:) == 3)
                drawpolygon(app.DrawingAxes, 'Position', currNuc, 'color','c','label','nuc');
            end
            label = app.files(app.ImageEditField.Value).CELL(app.CellEditField.Value).LABELIMAGE;
            % Make the label magenta(nuc)/green(cyto)
            imshow(label+1, [0 0 0; 0 1 0; 1 0 1], 'initialmag', 'fit', 'parent', app.FeedbackAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.filesdir = uigetdir(app.filesdir, "Select folder with .mat files post MoiseNet");
            drawnow;
            figure(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            
            matfiles = dir(fullfile(app.filesdir,"corr_*.mat"));%% To eliminate overwriting behavior if corr_.mat exists.
            if isempty(matfiles)
                matfiles = dir(fullfile(app.filesdir,"data4_*.mat"));
            end
            app.filenames = {matfiles.name};
            app.files = struct("CHANNEL_RGB", [], "CZIFILENAME", [], "CZIFILEPATH", [], "CELL", []);
            for name = app.filenames
                app.files(end+1) = load(fullfile(app.filesdir, name{1})).DATA;    
            end
            
            if size(app.files, 2) > 1
                app.files = app.files(2:end);
                app.ImageEditField.Value = 1;
                app.CellEditField.Value = 1;
                
                app.NumImagesLabel.Text = string(size(app.files, 2));
                app.NumCellsLabel.Text = string(size(app.files(app.ImageEditField.Value).CELL, 2));
                app.redchan = app.files(app.ImageEditField.Value).CHANNEL_RGB(1);
                app.bluechan = app.files(app.ImageEditField.Value).CHANNEL_RGB(1);
                app.loadImages([1 3]);
            end
        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)
            if app.ImageEditField.Value < size(app.files, 2)
                app.ImageEditField.Value = app.ImageEditField.Value + 1;
                app.NumCellsLabel.Text = string(size(app.files(app.ImageEditField.Value).CELL, 2));
                app.CellEditField.Value = 1;
                app.loadImages([1 3]);
            end
        end

        % Button pushed function: PrevImageButton
        function PrevImageButtonPushed(app, event)
            if app.ImageEditField.Value > 1
                app.ImageEditField.Value = app.ImageEditField.Value - 1;
                app.NumCellsLabel.Text = string(size(app.files(app.ImageEditField.Value).CELL, 2));
                app.CellEditField.Value = 1;
                app.loadImages([1 3]);
            end
        end

        % Button pushed function: NextCellButton
        function NextCellButtonPushed(app, event)
            if app.CellEditField.Value < size(app.files(app.ImageEditField.Value).CELL, 2)
                app.CellEditField.Value = app.CellEditField.Value + 1;
                app.loadImages([1 3]);
            end
        end

        % Button pushed function: PrevCellButton
        function PrevCellButtonPushed(app, event)
            if app.CellEditField.Value > 1
                app.CellEditField.Value = app.CellEditField.Value - 1;
                app.loadImages([1 3]);
            end
        end

        % Button pushed function: DrawCellROIButton
        function DrawCellROIButtonPushed(app, event)
            app.files(app.ImageEditField.Value).CELL(app.CellEditField.Value).cellROI = [];
            app.loadImages([1 3]);
            cellROI = drawpolygon(app.DrawingAxes, 'color','y','label','cell');
            app.files(app.ImageEditField.Value).CELL(app.CellEditField.Value).cellROI = cellROI.Position;
        end

        % Button pushed function: DrawNucROIButton
        function DrawNucROIButtonPushed(app, event)
            app.files(app.ImageEditField.Value).CELL(app.CellEditField.Value).nucROI = [];
            app.loadImages([1 3]);
            nucROI = drawpolygon(app.DrawingAxes,'color','c','label','nuc');
            app.files(app.ImageEditField.Value).CELL(app.CellEditField.Value).nucROI = nucROI.Position;
        end

        % Value changed function: ImageEditField
        function ImageEditFieldValueChanged(app, event)
            app.CellEditField.Value = 1;
            app.loadImages([1 3]);
        end

        % Value changed function: CellEditField
        function CellEditFieldValueChanged(app, event)
            app.loadImages([1 3]);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            savedir = app.filesdir;% Save in the same folder.
            drawnow;
            figure(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            
            for f_ind = 1:length(app.files)
                file = app.files(f_ind);
                
                for c_ind = 1:length(file.CELL)
                    cellPos = app.files(f_ind).CELL(c_ind).cellROI;
                    nucPos = app.files(f_ind).CELL(c_ind).nucROI;
                    
                    if ~isempty(cellPos) && ~isempty(nucPos)
                        cellROI = images.roi.Polygon('Position', cellPos);
                        nucROI = images.roi.Polygon('Position', nucPos);
                        
                        rgb = app.files(f_ind).CELL(c_ind).IMAGE;
                        red = rgb(:,:,1);
                        gre = rgb(:,:,2);
                        blu = rgb(:,:,3);
                        
                        cellMask = createMask(cellROI, rgb);
                        nucMask = createMask(nucROI, rgb);
                        cytoMask = cellMask~=nucMask;
                        
                        signal = struct;
                        signal.nuc.red = mean(red(nucMask));
                        signal.nuc.green = mean(gre(nucMask));
                        signal.nuc.blue = mean(blu(nucMask));
                        signal.cyto.red = mean(red(cytoMask));
                        signal.cyto.green = mean(gre(cytoMask));
                        signal.cyto.blue = mean(blu(cytoMask));
                        signal.wholecell.red = mean(red(cellMask));
                        signal.wholecell.green = mean(gre(cellMask));
                        signal.wholecell.blue = mean(blu(cellMask));
                        
                        app.files(f_ind).CELL(c_ind).SIGNAL = signal; 
                    end
                end
                
                DATA = app.files(f_ind);
                save(fullfile(savedir, ['corr_data4_' char(DATA.CZIFILENAME(1:end-4)) '.mat']), "DATA");
            end
        end

        % Button pushed function: ShowRBButton
        function ShowRBButtonPushed(app, event)
            app.loadImages([1 3]);
        end

        % Button pushed function: DropCellButton
        function DropCellButtonPushed(app, event)
            app.files(app.ImageEditField.Value).CELL(app.CellEditField.Value) = [];
            app.NumCellsLabel.Text = string(size(app.files(app.ImageEditField.Value).CELL, 2));
            app.CellEditField.Value = min(length(app.files(app.ImageEditField.Value).CELL), app.CellEditField.Value);
            % If all cells get dropped from an image, then error will
            % occur, but to fix it we need better than the following.
%             if app.CellEditField.Value == 0
%                 app.ImageEditField.Value = app.ImageEditField.Value-1;
%                 app.NumCellsLabel.Text = string(size(app.files(app.ImageEditField.Value).CELL, 2));
%                 app.CellEditField.Value = length(app.files(app.ImageEditField.Value).CELL);
%                 app.NumImagesLabel.Text = string(size(app.files, 2));
%             end
            app.loadImages([1 3]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTCToolkitCheckCorrectCellsAppUIFigure = uifigure('Visible', 'off');
            app.CRTCToolkitCheckCorrectCellsAppUIFigure.Name = 'CRTC Toolkit - Check & Correct Cells App';
            app.CRTCToolkitCheckCorrectCellsAppUIFigure.Icon = 'icon.png';
            app.SelectFolderButton = uibutton(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ImageEditFieldLabel = uilabel(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            app.ImageEditFieldLabel.Text = 'Image';
            app.ImageEditField = uieditfield(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'numeric');
            app.ImageEditField.ValueChangedFcn = createCallbackFcn(app, @ImageEditFieldValueChanged, true);
            app.OutOfLabel = uilabel(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            app.OutOfLabel.Text = '/';
            app.NumImagesLabel = uilabel(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            app.NumImagesLabel.Text = '00';
            app.PrevImageButton = uibutton(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'push');
            app.PrevImageButton.ButtonPushedFcn = createCallbackFcn(app, @PrevImageButtonPushed, true);
            app.PrevImageButton.Text = 'Prev';
            app.NextImageButton = uibutton(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next';
            app.CellEditFieldLabel = uilabel(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            app.CellEditFieldLabel.Text = 'Cell';
            app.CellEditField = uieditfield(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'numeric');
            app.CellEditField.ValueChangedFcn = createCallbackFcn(app, @CellEditFieldValueChanged, true);
            app.OutOfLabel2 = uilabel(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            app.OutOfLabel2.Text = '/';
            app.NumCellsLabel = uilabel(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            app.NumCellsLabel.Text = '00';
            app.NextCellButton = uibutton(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'push');
            app.NextCellButton.ButtonPushedFcn = createCallbackFcn(app, @NextCellButtonPushed, true);
            app.NextCellButton.Text = 'Next';
            app.PrevCellButton = uibutton(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'push');
            app.PrevCellButton.ButtonPushedFcn = createCallbackFcn(app, @PrevCellButtonPushed, true);
            app.PrevCellButton.Text = 'Prev';
            app.DrawCellROIButton = uibutton(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'push');
            app.DrawCellROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawCellROIButtonPushed, true);
            app.DrawCellROIButton.Text = 'Draw Cell ROI';
            app.DrawNucROIButton = uibutton(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'push');
            app.DrawNucROIButton.ButtonPushedFcn = createCallbackFcn(app, @DrawNucROIButtonPushed, true);
            app.DrawNucROIButton.Text = 'Draw Nuc ROI';
            app.SaveDataButton = uibutton(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.CurrImageLabel = uilabel(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            app.CurrImageLabel.Text = {''; ''};
            app.ShowRBButton = uibutton(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'push');
            app.ShowRBButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRBButtonPushed, true);
            app.ShowRBButton.Text = 'Show RB';
            app.DropCellButton = uibutton(app.CRTCToolkitCheckCorrectCellsAppUIFigure, 'push');
            app.DropCellButton.ButtonPushedFcn = createCallbackFcn(app, @DropCellButtonPushed, true);
            app.DropCellButton.Text = 'Drop Cell';
            app.DrawingAxes = uiaxes(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            app.DrawingAxes.XTick = [];
            app.DrawingAxes.YTick = [];
            app.DrawingAxes.ZTick = 0;
            app.FeedbackAxes = uiaxes(app.CRTCToolkitCheckCorrectCellsAppUIFigure);
            xlabel(app.FeedbackAxes, 'Ubwonko Image')
            app.FeedbackAxes.XTick = [];
            app.FeedbackAxes.YTick = [];
            app.FeedbackAxes.ZTick = 0;
            % Show the figure after all components are created
            app.CRTCToolkitCheckCorrectCellsAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crtc_correct_cells_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTCToolkitCheckCorrectCellsAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTCToolkitCheckCorrectCellsAppUIFigure)
        end
    end
end