classdef crtc_get_cells_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTCToolkitGetCellsAppUIFigure  matlab.ui.Figure
        DONEWITHRECTButton              matlab.ui.control.Button
        DONEWITHCELLButton              matlab.ui.control.Button
        cellsEditField                  matlab.ui.control.NumericEditField
        cellsEditFieldLabel             matlab.ui.control.Label
        DRAWRECTANGLEButton             matlab.ui.control.Button
        FINDACELLLabel                  matlab.ui.control.Label
        CurrentCZIfileLabel             matlab.ui.control.Label
        SAVEDATAButton                  matlab.ui.control.Button
        ZSliceSpinner                   matlab.ui.control.Spinner
        ZSliceSpinnerLabel              matlab.ui.control.Label
        SELECTCZIFILEButton             matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        handles % Description
    end
    
    methods (Access = private)
        
        function clearROIAxes(app)
            cla(app.UIAxes2,'reset');
            app.UIAxes2.Box = 'on';
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
        end
        
        function switchROIButtons(app,ONorOFF)
            switch ONorOFF
                case 'ON'
                    % app.AdjustBrightnessSlider.Enable = 'off';
                    % app.AUTOROIButton.Enable = 'off';
                    % app.DRAWNUCROIButton.Enable = 'off';
                    % app.DRAWCELLROIButton.Enable = 'off';
                    app.DONEWITHCELLButton.Enable = 'on';
                case 'OFF'
                    % app.AdjustBrightnessSlider.Enable = 'off';
                    % app.AUTOROIButton.Enable = 'off';
                    % app.DRAWNUCROIButton.Enable = 'off';
                    % app.DRAWCELLROIButton.Enable = 'off';
                    app.DONEWITHCELLButton.Enable = 'off';
            end
            
        end
        
        function switchRECTButtons(app,ONorOFF)
            switch ONorOFF
                case 'ON'
                    app.DRAWRECTANGLEButton.Enable = 'on';
                    app.DONEWITHRECTButton.Enable = 'on';
                    app.ZSliceSpinner.Enable = 'on';
                case 'OFF'
                    app.DRAWRECTANGLEButton.Enable = 'off';
                    app.DONEWITHRECTButton.Enable = 'off';
                    app.ZSliceSpinner.Enable = 'off';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SELECTCZIFILEButton
        function SELECTCZIFILEButtonPushed(app, event)
            currdir = pwd;
            [app.handles.czifilename,app.handles.czipath] = uigetfile({'*.czi;*.lif'},'SELECT CZI or LIF FILE TO QUANTIFY');
            drawnow;
            figure(app.CRTCToolkitGetCellsAppUIFigure);
            if app.handles.czifilename == 0
                return
            else
                cd(app.handles.czipath);
                if ~isempty(dir(['data4_',app.handles.czifilename(1:end-4),'.mat']))
                    ANS = uiconfirm(app.CRTCToolkitGetCellsAppUIFigure,'data.mat file exists for this file. Sure to continue?','FILE EXISTS');
                    if ~strcmp(ANS,'OK')
                        cd(currdir);
                        return
                    end
                end
            end
            INPUTS = inputdlg({'Red','Green','Blue'},'Enter channels',[1,10;1,10;1,10],{'1','2','3'});
            app.handles.cr = str2double(INPUTS{1});
            app.handles.cg = str2double(INPUTS{2});
            app.handles.cb = str2double(INPUTS{3});
            
            % LOAD CZI, SET SLIDER AND TEXT, SHOW FIRST SLICE
            DATA = readczi_f(fullfile(app.handles.czipath,app.handles.czifilename));
            % Determine bit correction for imshow
            if isa(DATA.images{1},'uint16')
                app.handles.bitcorr = 2^16/2^12;
            elseif isa(DATA.images{1},'uint8')
                app.handles.bitcorr = 1;
            else
                errordlg('IMAGE BIT DEPTH IS NOT 8 or 12');
                return
            end
            app.CurrentCZIfileLabel.Text = DATA.filepath;
            app.handles.images = DATA.images;
            REDIMS = repmat(DATA.images{app.handles.cr}.*app.handles.bitcorr,[1,1,1,3]);
            app.handles.redims = permute(REDIMS,[1,2,4,3]);% MADE RED IMAGE INTO RGB
            Z = DATA.num_zslice;
            app.handles.nslice = Z;
            app.ZSliceSpinner.Limits = [1,Z];
            app.ZSliceSpinner.Value = 1;
            app.handles.hcurrim = imshow(app.handles.redims(:,:,:,1),[],...
                'initialmag','fit','parent',app.UIAxes);
            app.handles.currslice = 1;
            
            % INITIALIZE SAVE FILE & ROI AXES
            app.handles.saveparam = struct();
            app.handles.saveparam.CHANNEL_RGB = [app.handles.cr,app.handles.cg,app.handles.cb];
            app.handles.saveparam.CZIFILENAME = app.handles.czifilename;
            app.handles.saveparam.CZIFILEPATH = DATA.filepath;
            app.handles.ncell = 1;
            
            clearROIAxes(app);
            switchROIButtons(app,'OFF');
            switchRECTButtons(app,'ON');
            figure(app.CRTCToolkitGetCellsAppUIFigure);
        end

        % Value changed function: ZSliceSpinner
        function ZSliceSpinnerValueChanged(app, event)
            Z = app.ZSliceSpinner.Value;
            set(app.handles.hcurrim,'CData',app.handles.redims(:,:,:,Z));
            app.handles.currslice = Z;
        end

        % Button pushed function: DRAWRECTANGLEButton
        function DRAWRECTANGLEButtonPushed(app, event)
            clearROIAxes(app);
            app.FINDACELLLabel.Text = 'DRAW RECTANGLE AND PUSH "DONE"';
            app.FINDACELLLabel.FontColor = 'r';
            app.DRAWRECTANGLEButton.Text = 'Drawing RECT...';
            app.DRAWRECTANGLEButton.FontColor = 'r';
            app.DRAWRECTANGLEButton.Enable = 'off';
            if isfield(app.handles,'RECT')
                delete(app.handles.RECT);
                app.handles = rmfield(app.handles,'RECT');
            end
            % Select rect on axes1 and crop images.
            app.handles.RECT = drawrectangle('color','red','parent',app.UIAxes);
            app.DRAWRECTANGLEButton.Enable = 'on';
            app.DRAWRECTANGLEButton.Text = 'DRAW RECTANGLE';
            app.DRAWRECTANGLEButton.FontColor = 'k';
        end

        % Callback function
        function AdjustBrightnessSliderValueChanged(app, event)
            % DISABLED 2021/03/09
            value = app.AdjustBrightnessSlider.Value;
            app.handles.cRB = imadjust(app.handles.cRBori,[0,1/value]);
            set(app.handles.hcurrrectim,'CData',app.handles.cRB);
        end

        % Callback function
        function AUTOROIButtonPushed(app, event)
            % DISABLED 2021/03/09
            if isfield(app.handles,'CELL')
                delete(app.handles.CELL);
                app.handles = rmfield(app.handles,'CELL');
            end
            if isfield(app.handles,'NUC')
                delete(app.handles.NUC);
                app.handles = rmfield(app.handles,'NUC');
            end
            R = app.handles.cRB(:,:,2);
            B = app.handles.cRB(:,:,1);
            imseg = cat(3,R,zeros(size(R)),B);
            lab_im = rgb2lab(imseg);
            ab = lab_im(:,:,2:3);
            ab = im2single(ab);
            nColors = 3;
            pixel_labels = imsegkmeans(ab,nColors,'numattempts',3);
            bound = {};
            boundarea = [];
            for k = 1:3
                BW = pixel_labels==k;
                B = bwboundaries(BW);
                for n = 1:length(B)
                    boundarea = [boundarea;polyarea(B{n}(:,2),B{n}(:,1))];
                    %plot(B{n}(:,2),B{n}(:,1),'r');
                end
                bound = [bound;B];
            end
            [~,ii] = sort(boundarea,'descend');
            hold(app.UIAxes2,'on');
            cellRoiPos = bound{ii(2)};
            nucRoiPos = bound{ii(4)};
            npoint1 = length(cellRoiPos);
            if npoint1 > 30
                ind = 1:ceil(npoint1/15):npoint1;
            else
                ind = 1:npoint1;
            end
            npoint2 = length(nucRoiPos);
            if npoint2 > 30
                ind2 = 1:ceil(npoint2/15):npoint2;
            else
                ind2 = 1:npoint2;
            end
            app.handles.CELL = drawpolygon(app.UIAxes2,'pos',cellRoiPos(ind,2:-1:1),'color','y','label','cell');
            app.handles.NUC = drawpolygon(app.UIAxes2,'pos',nucRoiPos(ind2,2:-1:1),'color','c','label','nuc');
        end

        % Callback function
        function DRAWCELLROIButtonPushed(app, event)
            % DISABLED 2021/03/09
            if isfield(app.handles,'CELL')
                delete(app.handles.CELL);
                app.handles = rmfield(app.handles,'CELL');
            end
            app.handles.CELL = drawpolygon(app.UIAxes2,'color','y','label','cell');
            if isfield(app.handles,'NUC')
                bringToFront(app.handles.NUC);
            end
        end

        % Callback function
        function DRAWNUCROIButtonPushed(app, event)
            % DISABLED 2021/03/09
            if isfield(app.handles,'NUC')
                delete(app.handles.NUC);
                app.handles = rmfield(app.handles,'NUC');
            end
            app.handles.NUC = drawpolygon(app.UIAxes2,'color','c','label','nuc');
        end

        % Button pushed function: DONEWITHCELLButton
        function DONEWITHCELLButtonPushed(app, event)
            %             
            signal.nuc.red = [];
            signal.nuc.green = [];
            signal.nuc.blue = [];
            signal.cyto.red = [];
            signal.cyto.green = [];
            signal.cyto.blue = [];
            signal.wholecell.red = [];
            signal.wholecell.green = [];
            signal.wholecell.blue = [];
            
            % Record data and update axes1 image.
            app.handles.saveparam.CELL(app.handles.ncell).RECT = app.handles.RECTPOS;
            app.handles.saveparam.CELL(app.handles.ncell).SLICE = app.handles.currslice;
            app.handles.saveparam.CELL(app.handles.ncell).nucROI = [];
            app.handles.saveparam.CELL(app.handles.ncell).cellROI = [];
            app.handles.saveparam.CELL(app.handles.ncell).SIGNAL = signal;
            app.handles.saveparam.CELL(app.handles.ncell).IMAGE = cat(3,app.handles.cR,app.handles.cG,app.handles.cB);
            RECTMASK = createMask(app.handles.RECT);
            CURRRIM = app.handles.redims(:,:,:,app.handles.currslice);
            if isa(CURRRIM,'uint16')
                CURRRIM(:,:,2:3) = CURRRIM(:,:,2:3).*uint16(~RECTMASK);
            elseif isa(CURRRIM,'uint8')
                CURRRIM(:,:,2:3) = CURRRIM(:,:,2:3).*uint8(~RECTMASK);
            end
            app.handles.redims(:,:,:,app.handles.currslice) = CURRRIM;
            set(app.handles.hcurrim,'CData',app.handles.redims(:,:,:,app.handles.currslice));
            app.handles.ncell = app.handles.ncell+1;
            
            % Clean up all the ROIs
            delete(app.handles.RECT);
            app.handles = rmfield(app.handles,'RECT');
            
            clearROIAxes(app);
            app.cellsEditField.Value = app.handles.ncell - 1;
            switchROIButtons(app,'OFF');
            switchRECTButtons(app,'ON');
        end

        % Button pushed function: SAVEDATAButton
        function SAVEDATAButtonPushed(app, event)
            ANS = uiconfirm(app.CRTCToolkitGetCellsAppUIFigure,'Save data for this CZI?','Saving data...');
            if ~strcmp(ANS,'OK')
                return
            else
                currdir = pwd;
                DATA = app.handles.saveparam;
                cd(app.handles.czipath);
                save(['data4_',app.handles.czifilename(1:end-4),'.mat'],'DATA');
                cd(currdir);
            end
            clearROIAxes(app);
            switchROIButtons(app,'OFF');
            switchRECTButtons(app,'OFF');
        end

        % Button pushed function: DONEWITHRECTButton
        function DONEWITHRECTButtonPushed(app, event)
            if ~isfield(app.handles,'RECT')
                uialert(app.CRTCToolkitGetCellsAppUIFigure,'DRAW RECTANGLE FIRST','Error');
                return
            end
            %% Show the cropped image before to ask whether they want to move forward.
            Z = app.handles.currslice;
            R = app.handles.images{app.handles.cr}(:,:,Z);
            B = app.handles.images{app.handles.cb}(:,:,Z);
            RECTPOS = round(app.handles.RECT.Position);
            cR = imcrop(R, RECTPOS);
            cB = imcrop(B, RECTPOS);
            % Show cropped RB image on axes2.
            cRB = cat(3, cB, cR, cB).*app.handles.bitcorr;
            if isfield(app.handles,'hcurrectim')
                oldimg = app.handles.hcurrectim;
            else
                oldimg = 'skip';
            end
            app.handles.hcurrrectim = imshow(cRB,'initialmag','fit','parent',app.UIAxes2);
            
            ANS = uiconfirm(app.CRTCToolkitGetCellsAppUIFigure,'SATISFIED WITH RECTANGLE?','Rectangle selection');
            if ~strcmp(ANS,'OK')
                delete(app.handles.RECT);
                app.handles = rmfield(app.handles,'RECT');
                if ischar(oldimg) && strcmp(oldimg,'skip')
                    app.handles = rmfield(app.handles,'hcurrrectim');
                else
                    app.handles.hcurrrectim = oldimg;
                end
                return
            end
            % First gather all current info
            Z = app.handles.currslice;
            app.handles.R = app.handles.images{app.handles.cr}(:,:,Z);
            app.handles.G = app.handles.images{app.handles.cg}(:,:,Z);
            app.handles.B = app.handles.images{app.handles.cb}(:,:,Z);
            app.handles.RECTPOS = round(app.handles.RECT.Position);
            app.handles.cR = imcrop(app.handles.R,app.handles.RECTPOS);
            app.handles.cG = imcrop(app.handles.G,app.handles.RECTPOS);
            app.handles.cB = imcrop(app.handles.B,app.handles.RECTPOS);
            % Show cropped RB image on axes2.
            app.handles.cRB = cat(3,app.handles.cB,app.handles.cR,app.handles.cB).*app.handles.bitcorr;
            app.handles.cRBori = app.handles.cRB;
            app.handles.hcurrrectim = imshow(app.handles.cRB,'initialmag','fit','parent',app.UIAxes2);
            set(app.handles.RECT,'InteractionsAllowed','none');
            % app.AdjustBrightnessSlider.Value = 1;
            switchROIButtons(app,'ON');
            switchRECTButtons(app,'OFF');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTCToolkitGetCellsAppUIFigure = uifigure('Visible', 'off');
            app.CRTCToolkitGetCellsAppUIFigure.Name = 'CRTC Toolkit - Get Cells App';
            app.CRTCToolkitGetCellsAppUIFigure.Icon = 'icon.png';
            app.UIAxes = uiaxes(app.CRTCToolkitGetCellsAppUIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.CRTCToolkitGetCellsAppUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.SELECTCZIFILEButton = uibutton(app.CRTCToolkitGetCellsAppUIFigure, 'push');
            app.SELECTCZIFILEButton.ButtonPushedFcn = createCallbackFcn(app, @SELECTCZIFILEButtonPushed, true);
            app.SELECTCZIFILEButton.Text = 'SELECT CZI FILE';
            app.ZSliceSpinnerLabel = uilabel(app.CRTCToolkitGetCellsAppUIFigure);
            app.ZSliceSpinnerLabel.Text = 'Z Slice';
            app.ZSliceSpinner = uispinner(app.CRTCToolkitGetCellsAppUIFigure);
            app.ZSliceSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSliceSpinnerValueChanged, true);
            app.ZSliceSpinner.Value = 1;
            app.SAVEDATAButton = uibutton(app.CRTCToolkitGetCellsAppUIFigure, 'push');
            app.SAVEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATAButton.Text = 'SAVE DATA';
            app.CurrentCZIfileLabel = uilabel(app.CRTCToolkitGetCellsAppUIFigure);
            app.CurrentCZIfileLabel.Text = 'Current CZI file';
            app.FINDACELLLabel = uilabel(app.CRTCToolkitGetCellsAppUIFigure);
            app.FINDACELLLabel.Text = 'FIND A CELL';
            app.DRAWRECTANGLEButton = uibutton(app.CRTCToolkitGetCellsAppUIFigure, 'push');
            app.DRAWRECTANGLEButton.ButtonPushedFcn = createCallbackFcn(app, @DRAWRECTANGLEButtonPushed, true);
            app.DRAWRECTANGLEButton.Text = 'DRAW RECTANGLE';
            app.cellsEditFieldLabel = uilabel(app.CRTCToolkitGetCellsAppUIFigure);
            app.cellsEditFieldLabel.Text = 'cells';
            app.cellsEditField = uieditfield(app.CRTCToolkitGetCellsAppUIFigure, 'numeric');
            app.cellsEditField.Editable = 'off';
            app.DONEWITHCELLButton = uibutton(app.CRTCToolkitGetCellsAppUIFigure, 'push');
            app.DONEWITHCELLButton.ButtonPushedFcn = createCallbackFcn(app, @DONEWITHCELLButtonPushed, true);
            app.DONEWITHCELLButton.Text = 'DONE WITH CELL';
            app.DONEWITHRECTButton = uibutton(app.CRTCToolkitGetCellsAppUIFigure, 'push');
            app.DONEWITHRECTButton.ButtonPushedFcn = createCallbackFcn(app, @DONEWITHRECTButtonPushed, true);
            app.DONEWITHRECTButton.Text = 'DONE WITH RECT';
            % Show the figure after all components are created
            app.CRTCToolkitGetCellsAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crtc_get_cells_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTCToolkitGetCellsAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTCToolkitGetCellsAppUIFigure)
        end
    end
end