classdef crtc_mip_from_czi_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTCToolkitMIPFromCZIAppUIFigure  matlab.ui.Figure
        CreateMIPButton          matlab.ui.control.Button
        Channel3DropDown         matlab.ui.control.DropDown
        Channel3DropDownLabel    matlab.ui.control.Label
        Channel2DropDown         matlab.ui.control.DropDown
        Channel2DropDownLabel    matlab.ui.control.Label
        Channel1DropDown         matlab.ui.control.DropDown
        Channel1DropDownLabel    matlab.ui.control.Label
        CZIFolderEditField       matlab.ui.control.EditField
        CZIFolderEditFieldLabel  matlab.ui.control.Label
        BrowseButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            czidir = uigetdir(pwd, 'Select the folder containing .czi files');
            drawnow;
            figure(app.CRTCToolkitMIPFromCZIAppUIFigure);
            if czidir == 0
                return
            else
                app.CZIFolderEditField.Value = czidir;
            end
        end

        % Button pushed function: CreateMIPButton
        function CreateMIPButtonPushed(app, event)
            channel2color = {app.Channel1DropDown.Value; app.Channel2DropDown.Value; app.Channel3DropDown.Value};
            
            if ~isfolder(app.CZIFolderEditField.Value)
                uiconfirm(app.CRTCToolkitMIPFromCZIAppUIFigure, "Please provide a valid CZI directory first!", "No valid CZI directory provided");
                return;
            end
            
            currdir = pwd;
            cd(app.CZIFolderEditField.Value);
            app.CreateMIPButton.Text = "Creating...";
            drawnow;
            make_mip_from_czi_f(channel2color);
            cd(currdir);
            app.CreateMIPButton.Text = "Completed";
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTCToolkitMIPFromCZIAppUIFigure = uifigure('Visible', 'off');
            app.CRTCToolkitMIPFromCZIAppUIFigure.Name = 'CRTC Toolkit - MIP From CZI App';
            app.CRTCToolkitMIPFromCZIAppUIFigure.Icon = 'icon.png';
            app.BrowseButton = uibutton(app.CRTCToolkitMIPFromCZIAppUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.CZIFolderEditFieldLabel = uilabel(app.CRTCToolkitMIPFromCZIAppUIFigure);
            app.CZIFolderEditFieldLabel.Text = 'CZI Folder';
            app.CZIFolderEditField = uieditfield(app.CRTCToolkitMIPFromCZIAppUIFigure, 'text');
            app.Channel1DropDownLabel = uilabel(app.CRTCToolkitMIPFromCZIAppUIFigure);
            app.Channel1DropDownLabel.Text = 'Channel 1';
            app.Channel1DropDown = uidropdown(app.CRTCToolkitMIPFromCZIAppUIFigure);
            app.Channel1DropDown.Items = {'Red', 'Green', 'Blue'};
            app.Channel1DropDown.ItemsData = {'R', 'G', 'B'};
            app.Channel1DropDown.Value = 'R';
            app.Channel2DropDownLabel = uilabel(app.CRTCToolkitMIPFromCZIAppUIFigure);
            app.Channel2DropDownLabel.Text = 'Channel 2';
            app.Channel2DropDown = uidropdown(app.CRTCToolkitMIPFromCZIAppUIFigure);
            app.Channel2DropDown.Items = {'Red', 'Green', 'Blue'};
            app.Channel2DropDown.ItemsData = {'R', 'G', 'B'};
            app.Channel2DropDown.Value = 'G';
            app.Channel3DropDownLabel = uilabel(app.CRTCToolkitMIPFromCZIAppUIFigure);
            app.Channel3DropDownLabel.Text = 'Channel 3';
            app.Channel3DropDown = uidropdown(app.CRTCToolkitMIPFromCZIAppUIFigure);
            app.Channel3DropDown.Items = {'Red', 'Green', 'Blue'};
            app.Channel3DropDown.ItemsData = {'R', 'G', 'B'};
            app.Channel3DropDown.Value = 'B';
            app.CreateMIPButton = uibutton(app.CRTCToolkitMIPFromCZIAppUIFigure, 'push');
            app.CreateMIPButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMIPButtonPushed, true);
            app.CreateMIPButton.Text = 'Create MIP';
            % Show the figure after all components are created
            app.CRTCToolkitMIPFromCZIAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crtc_mip_from_czi_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTCToolkitMIPFromCZIAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTCToolkitMIPFromCZIAppUIFigure)
        end
    end
end