classdef crtc_open_fig_file_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTCToolkitOpenfigFileUIFigure  matlab.ui.Figure
        BrowseButton                    matlab.ui.control.Button
        FigureFileEditFieldLabel        matlab.ui.control.Label
        FigureFileEditField             matlab.ui.control.EditField
        OpenButton                      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [figfilename,figpath] = uigetfile('*.fig','Select the .fig file');
            drawnow;
            figure(app.CRTCToolkitOpenfigFileUIFigure);
            if figfilename == 0
                return
            else
                app.FigureFileEditField.Value = fullfile(figpath, figfilename);
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            if ~isfile(app.FigureFileEditField.Value)
                uiconfirm(app.CRTCToolkitOpenfigFileUIFigure, "Please provide a valid .fig file generated by the CRTC toolkit", "Invalid file provided");
                return;
            end
            
            openfig(app.FigureFileEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTCToolkitOpenfigFileUIFigure = uifigure('Visible', 'off');
            app.CRTCToolkitOpenfigFileUIFigure.Name = 'CRTC Toolkit - Open .fig File';
            app.CRTCToolkitOpenfigFileUIFigure.Icon = 'icon.png';
            app.BrowseButton = uibutton(app.CRTCToolkitOpenfigFileUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.FigureFileEditFieldLabel = uilabel(app.CRTCToolkitOpenfigFileUIFigure);
            app.FigureFileEditFieldLabel.Text = 'Figure File';
            app.FigureFileEditField = uieditfield(app.CRTCToolkitOpenfigFileUIFigure, 'text');
            app.OpenButton = uibutton(app.CRTCToolkitOpenfigFileUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            % Show the figure after all components are created
            app.CRTCToolkitOpenfigFileUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crtc_open_fig_file_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTCToolkitOpenfigFileUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTCToolkitOpenfigFileUIFigure)
        end
    end
end