classdef crtc_per_condition_analysis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTCToolkitPerConditionAnalysisAppUIFigure  matlab.ui.Figure
        ExperimentalconditionEditFieldLabel  matlab.ui.control.Label
        ExperimentalconditionEditField  matlab.ui.control.EditField
        SavedataCheckBox                matlab.ui.control.CheckBox
        PlottingPanel                   matlab.ui.container.Panel
        IndividualcellcolorDropDownLabel  matlab.ui.control.Label
        IndividualcellcolorDropDown     matlab.ui.control.DropDown
        MeancolorDropDownLabel          matlab.ui.control.Label
        MeancolorDropDown               matlab.ui.control.DropDown
        DataFolderEditFieldLabel        matlab.ui.control.Label
        DataFolderEditField             matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        StartanalysisButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            datadir = uigetdir(pwd, 'Select the folder containing subfolders for each fly');
            drawnow;
            figure(app.CRTCToolkitPerConditionAnalysisAppUIFigure);
            if datadir == 0
                return
            else
                app.DataFolderEditField.Value = datadir;
            end
        end

        % Button pushed function: StartanalysisButton
        function StartanalysisButtonPushed(app, event)
            loadcolors;
            expcondname = app.ExperimentalconditionEditField.Value;
            indivcolor = eval(app.IndividualcellcolorDropDown.Value);
            meancolor = eval(app.MeancolorDropDown.Value);
            dosave = app.SavedataCheckBox.Value;
            
            if ~isfolder(app.DataFolderEditField.Value)
                uiconfirm(app.CRTCToolkitPerConditionAnalysisAppUIFigure, "Please provide a valid data directory first!", "No data directory provided");
                return;
            end
            currdir = pwd;
            cd(app.DataFolderEditField.Value);
            app.StartanalysisButton.Text = "Analyzing...";
            drawnow;
            crtc_per_condition_analysis_f(expcondname,indivcolor,meancolor,dosave);
            cd(currdir);
            app.StartanalysisButton.Text = "Complete!";
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTCToolkitPerConditionAnalysisAppUIFigure = uifigure('Visible', 'off');
            app.CRTCToolkitPerConditionAnalysisAppUIFigure.Name = 'CRTC Toolkit - Per Condition Analysis App';
            app.CRTCToolkitPerConditionAnalysisAppUIFigure.Icon = 'icon.png';
            app.ExperimentalconditionEditFieldLabel = uilabel(app.CRTCToolkitPerConditionAnalysisAppUIFigure);
            app.ExperimentalconditionEditFieldLabel.Text = 'Experimental condition';
            app.ExperimentalconditionEditField = uieditfield(app.CRTCToolkitPerConditionAnalysisAppUIFigure, 'text');
            app.SavedataCheckBox = uicheckbox(app.CRTCToolkitPerConditionAnalysisAppUIFigure);
            app.SavedataCheckBox.Text = 'Save data';
            app.SavedataCheckBox.Value = true;
            app.PlottingPanel = uipanel(app.CRTCToolkitPerConditionAnalysisAppUIFigure);
            app.PlottingPanel.Title = 'Plotting';
            app.IndividualcellcolorDropDownLabel = uilabel(app.PlottingPanel);
            app.IndividualcellcolorDropDownLabel.Text = 'Individual cell color';
            app.IndividualcellcolorDropDown = uidropdown(app.PlottingPanel);
            app.IndividualcellcolorDropDown.Items = {'Light blue', 'Dark blue', 'Light green', 'Dark green', 'Light red', 'Dark red', 'Light orange', 'Dark orange', 'Light purple', 'Dark purple', 'Light gray', 'Dark gray', 'Light brown', 'Dark brown', 'Black'};
            app.IndividualcellcolorDropDown.ItemsData = {'LightBlue', 'DarkBlue', 'LightGreen', 'DarkGreen', 'LightRed', 'DarkRed', 'LightOrange', 'DarkOrange', 'LightPurple', 'DarkPurple', 'LightGray', 'DarkGray', 'LightBrown', 'DarkBrown', 'Black'};
            app.IndividualcellcolorDropDown.Value = 'LightGreen';
            app.MeancolorDropDownLabel = uilabel(app.PlottingPanel);
            app.MeancolorDropDownLabel.Text = 'Mean color';
            app.MeancolorDropDown = uidropdown(app.PlottingPanel);
            app.MeancolorDropDown.Items = {'Light blue', 'Dark blue', 'Light green', 'Dark green', 'Light red', 'Dark red', 'Light orange', 'Dark orange', 'Light purple', 'Dark purple', 'Light gray', 'Dark gray', 'Light brown', 'Dark brown', 'Black'};
            app.MeancolorDropDown.ItemsData = {'LightBlue', 'DarkBlue', 'LightGreen', 'DarkGreen', 'LightRed', 'DarkRed', 'LightOrange', 'DarkOrange', 'LightPurple', 'DarkPurple', 'LightGray', 'DarkGray', 'LightBrown', 'DarkBrown', 'Black'};
            app.MeancolorDropDown.Value = 'DarkGreen';
            app.DataFolderEditFieldLabel = uilabel(app.CRTCToolkitPerConditionAnalysisAppUIFigure);
            app.DataFolderEditFieldLabel.Text = 'Data Folder';
            app.DataFolderEditField = uieditfield(app.CRTCToolkitPerConditionAnalysisAppUIFigure, 'text');
            app.Label = uilabel(app.CRTCToolkitPerConditionAnalysisAppUIFigure);
            app.Label.Text = 'Select a folder that contains ''FLY01'', ''FLY02'', etc., folders, with each containing data4*.mat files.';
            app.BrowseButton = uibutton(app.CRTCToolkitPerConditionAnalysisAppUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.StartanalysisButton = uibutton(app.CRTCToolkitPerConditionAnalysisAppUIFigure, 'push');
            app.StartanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartanalysisButtonPushed, true);
            app.StartanalysisButton.Text = 'Start analysis';
            % Show the figure after all components are created
            app.CRTCToolkitPerConditionAnalysisAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crtc_per_condition_analysis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTCToolkitPerConditionAnalysisAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTCToolkitPerConditionAnalysisAppUIFigure)
        end
    end
end