classdef crtc_segment_cells_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTCToolkitSegmentCellsAppUIFigure  matlab.ui.Figure
        Label                          matlab.ui.control.Label
        DataFolderEditField            matlab.ui.control.EditField
        DataFolderEditFieldLabel       matlab.ui.control.Label
        BrowseButton_2                 matlab.ui.control.Button
        ProcessButton                  matlab.ui.control.Button
        BrowseButton                   matlab.ui.control.Button
        NetFileLocationEditField       matlab.ui.control.EditField
        NetFileLocationEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isdeployed 
                [~, result] = system('path');
                exedir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                exepath = fullfile(exedir, "crtcUbwonkoNet_resnet50.mat");
                if isfile(exepath)
                    app.NetFileLocationEditField.Value = exepath;
                else
                    exepath = which("crtcUbwonkoNet_resnet50.mat");
                    if isfile(exepath)
                        app.NetFileLocationEditField.Value = exepath;
                    end
                end
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [netfilename,netpath] = uigetfile('*.mat','Select the location of crtcUbwonkoNet_resnet50.mat');
            drawnow;
            figure(app.CRTCToolkitSegmentCellsAppUIFigure);
            if netfilename == 0
                return
            else
                app.NetFileLocationEditField.Value = fullfile(netpath, netfilename);
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            imgSz = 224;
            if ~isfile(app.NetFileLocationEditField.Value)
                uiconfirm(app.CRTCToolkitSegmentCellsAppUIFigure, "Please provide a valid location for the resnet first!", "No network provided");
                return;
            elseif ~isfolder(app.DataFolderEditField.Value)
                uiconfirm(app.CRTCToolkitSegmentCellsAppUIFigure, "Please provide a valid data directory first!", "No data directory provided");
                return;
            end
            currdir = pwd;
            cd(app.DataFolderEditField.Value);
            app.ProcessButton.Text = "Processing!";
            drawnow;
            crtc_batch_process_by_ubwonkonet(app.NetFileLocationEditField.Value,imgSz);
            cd(currdir);
            app.ProcessButton.Text = "Completed";
            drawnow;
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            datadir = uigetdir(pwd, 'Select the folder containing data4*.mat files');
            drawnow;
            figure(app.CRTCToolkitSegmentCellsAppUIFigure);
            if datadir == 0
                return
            else
                app.DataFolderEditField.Value = datadir;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTCToolkitSegmentCellsAppUIFigure = uifigure('Visible', 'off');
            app.CRTCToolkitSegmentCellsAppUIFigure.Name = 'CRTC Toolkit - Segment Cells App';
            app.CRTCToolkitSegmentCellsAppUIFigure.Icon = 'icon.png';
            app.NetFileLocationEditFieldLabel = uilabel(app.CRTCToolkitSegmentCellsAppUIFigure);
            app.NetFileLocationEditFieldLabel.Text = 'Net File Location';
            app.NetFileLocationEditField = uieditfield(app.CRTCToolkitSegmentCellsAppUIFigure, 'text');
            app.BrowseButton = uibutton(app.CRTCToolkitSegmentCellsAppUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ProcessButton = uibutton(app.CRTCToolkitSegmentCellsAppUIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.BrowseButton_2 = uibutton(app.CRTCToolkitSegmentCellsAppUIFigure, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.DataFolderEditFieldLabel = uilabel(app.CRTCToolkitSegmentCellsAppUIFigure);
            app.DataFolderEditFieldLabel.Text = 'Data Folder';
            app.DataFolderEditField = uieditfield(app.CRTCToolkitSegmentCellsAppUIFigure, 'text');
            app.Label = uilabel(app.CRTCToolkitSegmentCellsAppUIFigure);
            app.Label.Text = {'For "Net File Location", find and select the crtcUbwonkoNet_resnet50.mat file.'; ''; 'For "Data Folder", find and select the folder which contains the "data4*.mat" files generated by the '; 'CRTC Toolkit - Get Cells App. If data4*.mat files contain manual ROI data, they will be preserved. If'; 'data4*.mat files do not contain manual ROI data, those fields will be populated with ubwonkonet '; 'determined signal values.'};
            % Show the figure after all components are created
            app.CRTCToolkitSegmentCellsAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crtc_segment_cells_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTCToolkitSegmentCellsAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTCToolkitSegmentCellsAppUIFigure)
        end
    end
end