classdef crtc_toolkit_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRTCToolkitUIFigure         matlab.ui.Figure
        CoreworkflowPanel           matlab.ui.container.Panel
        GetCellsButton              matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        SegmentCellsButton          matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        CheckandCorrectCellsButton  matlab.ui.control.Button
        Label_3                     matlab.ui.control.Label
        DataanalysisPanel           matlab.ui.container.Panel
        PerConditionAnalysisButton  matlab.ui.control.Button
        Label_4                     matlab.ui.control.Label
        CrossGroupAnalysisButton    matlab.ui.control.Button
        Label_5                     matlab.ui.control.Label
        ConveniencetoolsPanel       matlab.ui.container.Panel
        ManualAnnotationButton      matlab.ui.control.Button
        Label_6                     matlab.ui.control.Label
        MIPfromCZIButton            matlab.ui.control.Button
        Label_7                     matlab.ui.control.Label
        OpenfigFileButton           matlab.ui.control.Button
        Label_8                     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetCellsButton
        function GetCellsButtonPushed(app, event)
            crtc_get_cells_app;
        end

        % Button pushed function: SegmentCellsButton
        function SegmentCellsButtonPushed(app, event)
            crtc_segment_cells_app;
        end

        % Button pushed function: CheckandCorrectCellsButton
        function CheckandCorrectCellsButtonPushed(app, event)
            crtc_correct_cells_app;
        end

        % Button pushed function: PerConditionAnalysisButton
        function PerConditionAnalysisButtonPushed(app, event)
            crtc_per_condition_analysis_app;
        end

        % Button pushed function: CrossGroupAnalysisButton
        function CrossGroupAnalysisButtonPushed(app, event)
            crtc_analyze_across_groups_app;
        end

        % Button pushed function: ManualAnnotationButton
        function ManualAnnotationButtonPushed(app, event)
            crtc_manual_annotation_app;
        end

        % Button pushed function: MIPfromCZIButton
        function MIPfromCZIButtonPushed(app, event)
            crtc_mip_from_czi_app;
        end

        % Button pushed function: OpenfigFileButton
        function OpenfigFileButtonPushed(app, event)
            crtc_open_fig_file_app;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRTCToolkitUIFigure = uifigure('Visible', 'off');
            app.CRTCToolkitUIFigure.Name = 'CRTC Toolkit';
            app.CRTCToolkitUIFigure.Icon = 'icon.png';
            app.CoreworkflowPanel = uipanel(app.CRTCToolkitUIFigure);
            app.CoreworkflowPanel.Title = 'Core workflow';
            app.GetCellsButton = uibutton(app.CoreworkflowPanel, 'push');
            app.GetCellsButton.ButtonPushedFcn = createCallbackFcn(app, @GetCellsButtonPushed, true);
            app.GetCellsButton.Text = 'Get Cells';
            app.Label = uilabel(app.CoreworkflowPanel);
            app.Label.Text = {'Select CZI files and mark the position of cells by moving along the z-axis, locating the'; 'vertical center of each cell, and drawing a rectangle around it. Data will be saved for each '; 'CZI image to a data4*.mat file which will be used by the later stages of toolkit.'};
            app.SegmentCellsButton = uibutton(app.CoreworkflowPanel, 'push');
            app.SegmentCellsButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentCellsButtonPushed, true);
            app.SegmentCellsButton.Text = 'Segment Cells';
            app.Label_2 = uilabel(app.CoreworkflowPanel);
            app.Label_2.Text = {'Provide a directory containing data4*.mat files from the previous stage, along with the'; 'location of the ResNet. Cells will be automatically analyzed by the network, and the'; 'data4*.mat files will be updated with cytoplasmic/nuclear segmentation data.'};
            app.CheckandCorrectCellsButton = uibutton(app.CoreworkflowPanel, 'push');
            app.CheckandCorrectCellsButton.ButtonPushedFcn = createCallbackFcn(app, @CheckandCorrectCellsButtonPushed, true);
            app.CheckandCorrectCellsButton.Text = {'Check and'; 'Correct Cells'};
            app.Label_3 = uilabel(app.CoreworkflowPanel);
            app.Label_3.Text = {'Provide a directory containing data4*.mat files from the previous stage. The user interface'; 'will allow verification of network-generated segmentation, and correction in situations where '; 'the segmentation is wrong. New corr_data4*.mat files will be generated where necessary.'};
            app.DataanalysisPanel = uipanel(app.CRTCToolkitUIFigure);
            app.DataanalysisPanel.Title = 'Data analysis';
            app.PerConditionAnalysisButton = uibutton(app.DataanalysisPanel, 'push');
            app.PerConditionAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @PerConditionAnalysisButtonPushed, true);
            app.PerConditionAnalysisButton.Text = {'Per Condition'; 'Analysis'};
            app.Label_4 = uilabel(app.DataanalysisPanel);
            app.Label_4.Text = {'Provide a directory containing fly subfolders, each of which contains data4*.mat and/or '; 'corr_data4*.mat files. Analysis of nuclear localization will be conducted, and the resulting '; 'data can be outputted & saved in both an Excel spreadsheet and a series of figures.'};
            app.CrossGroupAnalysisButton = uibutton(app.DataanalysisPanel, 'push');
            app.CrossGroupAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @CrossGroupAnalysisButtonPushed, true);
            app.CrossGroupAnalysisButton.Text = {'Cross-Group'; 'Analysis'};
            app.Label_5 = uilabel(app.DataanalysisPanel);
            app.Label_5.Text = {'Provide a directory containing subfolders for each experimental group, each of which'; 'contains fly subfolders with data4*.mat and/or corr_data4*.mat files. In addition to per '; 'condition analysis, statistical analysis across groups will be conducted and optionally saved.'};
            app.ConveniencetoolsPanel = uipanel(app.CRTCToolkitUIFigure);
            app.ConveniencetoolsPanel.Title = 'Convenience tools';
            app.ManualAnnotationButton = uibutton(app.ConveniencetoolsPanel, 'push');
            app.ManualAnnotationButton.ButtonPushedFcn = createCallbackFcn(app, @ManualAnnotationButtonPushed, true);
            app.ManualAnnotationButton.Text = {'Manual'; 'Annotation'};
            app.Label_6 = uilabel(app.ConveniencetoolsPanel);
            app.Label_6.Text = {'Select CZI files and annotate cells by moving along the z-axis, drawing a rectangle around '; 'the vertical center of each cell. Add nuclear/cytoplasmic segmentation manually or via '; 'k-means segmentation. Data will be saved for each CZI image to a data4*.mat file.'};
            app.MIPfromCZIButton = uibutton(app.ConveniencetoolsPanel, 'push');
            app.MIPfromCZIButton.ButtonPushedFcn = createCallbackFcn(app, @MIPfromCZIButtonPushed, true);
            app.MIPfromCZIButton.Text = 'MIP from CZI';
            app.Label_7 = uilabel(app.ConveniencetoolsPanel);
            app.Label_7.Text = {'Generate maximum intensity projections figures from a directory containing CZI files. The'; 'tool will make a subfolder per CZI file, saving .png images of MIP per channel as well '; 'as merged MIP.'};
            app.OpenfigFileButton = uibutton(app.ConveniencetoolsPanel, 'push');
            app.OpenfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfigFileButtonPushed, true);
            app.OpenfigFileButton.Text = 'Open .fig File';
            app.Label_8 = uilabel(app.ConveniencetoolsPanel);
            app.Label_8.Text = {'Reopen .fig files saved by other portions of the CRTC toolkit. The toolbar options present in'; 'the opened files can be used to save the figures as images for external use and sharing, as'; 'well as to adjust axes, etc.'};
            % Show the figure after all components are created
            app.CRTCToolkitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crtc_toolkit_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRTCToolkitUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRTCToolkitUIFigure)
        end
    end
end